classdef FinalApp_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TortioninArbitraryShaftsUIFigure  matlab.ui.Figure
        HelpMenu                     matlab.ui.container.Menu
        AppGuideMenu                 matlab.ui.container.Menu
        TheoryManualMenu             matlab.ui.container.Menu
        FailcaseManualMenu           matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        StandardShapesTab            matlab.ui.container.Tab
        ShapeSelectionPanel          matlab.ui.container.Panel
        ShapeDropDownLabel           matlab.ui.control.Label
        ShapeDropDown                matlab.ui.control.DropDown
        RadiusinmEditFieldLabel      matlab.ui.control.Label
        RadiusinmEditField           matlab.ui.control.NumericEditField
        MajorAxisinmEditFieldLabel   matlab.ui.control.Label
        MajorAxisinmEditField        matlab.ui.control.NumericEditField
        MinorAxisinmEditFieldLabel   matlab.ui.control.Label
        MinorAxisinmEditField        matlab.ui.control.NumericEditField
        MaterialSelectionPanel_2     matlab.ui.container.Panel
        MaterialSwitch_2             matlab.ui.control.Switch
        MaterialDropDown_2Label      matlab.ui.control.Label
        MaterialDropDown_2           matlab.ui.control.DropDown
        GGPaEditField_2Label         matlab.ui.control.Label
        GGPaEditField_2              matlab.ui.control.NumericEditField
        OutputPanel                  matlab.ui.container.Panel
        uEditField_2Label            matlab.ui.control.Label
        uEditField_2                 matlab.ui.control.EditField
        vEditField_2Label            matlab.ui.control.Label
        vEditField_2                 matlab.ui.control.EditField
        wEditField_2Label            matlab.ui.control.Label
        wEditField_2                 matlab.ui.control.EditField
        zxinMPaEditField_2Label      matlab.ui.control.Label
        zxinMPaEditField_2           matlab.ui.control.EditField
        zyinMPaEditField_2Label      matlab.ui.control.Label
        zyinMPaEditField_2           matlab.ui.control.EditField
        TorqueinNmEditFieldLabel     matlab.ui.control.Label
        TorqueinNmEditField          matlab.ui.control.EditField
        InputEditFieldLabel          matlab.ui.control.Label
        InputEditField               matlab.ui.control.NumericEditField
        CalculateButton_2            matlab.ui.control.Button
        x108Label                    matlab.ui.control.Label
        RefreshButton_3              matlab.ui.control.Button
        CheckEditField_2Label        matlab.ui.control.Label
        CheckEditField_2             matlab.ui.control.EditField
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        ArbitraryShapesTab           matlab.ui.container.Tab
        FunctionInputEditFieldLabel  matlab.ui.control.Label
        FunctionInputEditField       matlab.ui.control.EditField
        OutputPanel_2                matlab.ui.container.Panel
        uEditField_3Label            matlab.ui.control.Label
        uEditField_3                 matlab.ui.control.EditField
        vEditField_3Label            matlab.ui.control.Label
        vEditField_3                 matlab.ui.control.EditField
        wEditField_3Label            matlab.ui.control.Label
        wEditField_3                 matlab.ui.control.EditField
        zxinMPaLabel                 matlab.ui.control.Label
        zxinMPaEditField             matlab.ui.control.EditField
        zyinMPaEditFieldLabel        matlab.ui.control.Label
        zyinMPaEditField             matlab.ui.control.EditField
        CalculateButton              matlab.ui.control.Button
        MaterialSelectionPanel_3     matlab.ui.container.Panel
        MaterialSwitch_3             matlab.ui.control.Switch
        GGPaLabel                    matlab.ui.control.Label
        GGPaEditField                matlab.ui.control.NumericEditField
        MaterialDropDown_2Label_2    matlab.ui.control.Label
        MaterialDropDown_3           matlab.ui.control.DropDown
        CheckEditFieldLabel          matlab.ui.control.Label
        CheckEditField               matlab.ui.control.EditField
        InputEditField_2Label        matlab.ui.control.Label
        InputEditField_2             matlab.ui.control.NumericEditField
        RefreshButton_4              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Button4Pushed(app, event)
            app.ButtonGroup.Visible='off';
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            value = app.ShapeDropDown.Value;
            % Checking if the shape is Circle or Ellipse in the shape
            % selection
            if strcmp(value,'Ellipse')
                set(app.RadiusinmEditField,'Enable','off')
                %Disabling radius input for circle
                set(app.RadiusinmEditFieldLabel,'Enable','off')
                set(app.MajorAxisinmEditField,'Enable','on')
                set(app.MinorAxisinmEditField,'Enable','on')
                %Enabling major and minor axis input for ellipse
                set(app.MajorAxisinmEditFieldLabel,'Enable','on')
                set(app.MinorAxisinmEditFieldLabel,'Enable','on')
            else
                set(app.RadiusinmEditField,'Enable','on')
                %Enabling radius input for circle
                set(app.RadiusinmEditFieldLabel,'Enable','on')
                set(app.MajorAxisinmEditField,'Enable','off')
                set(app.MinorAxisinmEditField,'Enable','off')
                %Disabling major and minor axis input for circle
                set(app.MajorAxisinmEditFieldLabel,'Enable','off')
                set(app.MinorAxisinmEditFieldLabel,'Enable','off')
            end
        end

        % Value changed function: MaterialSwitch_2
        function MaterialSwitch_2ValueChanged(app, event)
            value = app.MaterialSwitch_2.Value;
            %Checking if the Material Toggle is custom i.e. 'G' or fixed
            %material
            if strcmp(value,'G')
                set(app.MaterialDropDown_2,'Enable','off')
                set(app.MaterialDropDown_2Label,'Enable','off')
                set(app.GGPaEditField_2,'Enable','on')
                set(app.GGPaEditField_2Label,'Enable','on')
            else
                set(app.MaterialDropDown_2,'Enable','on')
                set(app.MaterialDropDown_2Label,'Enable','on')
                set(app.GGPaEditField_2,'Enable','off')
                set(app.GGPaEditField_2Label,'Enable','off')
            end
        end

        % Value changed function: MaterialSwitch_3
        function MaterialSwitch_3ValueChanged(app, event)
            value = app.MaterialSwitch_3.Value;
            %Checking if the Material Toggle is custom i.e. 'G' or fixed
            %material
            if strcmp(value,'G')
                set(app.MaterialDropDown_3,'Enable','off')
                set(app.MaterialDropDown_2Label_2,'Enable','off')
                set(app.GGPaEditField,'Enable','on')
                %Enabling Input field for G
                set(app.GGPaLabel,'Enable','on')
            else
                set(app.MaterialDropDown_3,'Enable','on')
                set(app.MaterialDropDown_2Label_2,'Enable','on')
                set(app.GGPaEditField,'Enable','off')
                %Disabling input fielf for G
                set(app.GGPaLabel,'Enable','off')
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            val1 = app.MaterialSwitch_2.Value;
            %Checking if the Material is given or G is given
            value1 = convertCharsToStrings(val1);%Converting input From char to strings
            if value1 == 'G'
                g = app.GGPaEditField_2.Value;
                %Input is g in GPA. So multiplying with a factor of 10^9.
                g = g*1000000000;
            else
                %If a particular material is chosen value is assigned as
                %per our collected Data
               
                val2 = app.MaterialDropDown_2.Value;
                value = convertCharsToStrings(val2);
                if value == 'Steel'
                    % G for steel is 200 GPa
                    g = 200 *1000000000;
                elseif value == 'Stainless_Steel'
                    % G for stainless steel is 193 GPa
                    g = 193 *1000000000;
                elseif value == 'Aluminium' 
                    % G for aluminium is 70.9 GPa
                    g = 70.9 *1000000000;
                elseif value =='Cast_Iron'
                    % G for cast iron is 120.5 GPa
                    g = 120.5 *1000000000;
                elseif value =='Copper'
                    % G for copper is 117 GPa
                    g = 117 *1000000000;
                elseif value == 'Yellow_Brass'
                    % G for yellow brass is 105 GPa
                    g = 105 *1000000000;
                elseif value =='Titanium'
                    % G for titanium is 114 GPa
                    g = 114 *1000000000;
                elseif value =='Concrete'
                    % G for concrete is 25 GPa
                    g = 25 *1000000000;
                elseif value=='Commercial_Bronze'
                    % G for commercial bronze is 110 GPa
                    g = 110 *1000000000';
                end
            %g = g/1000000000
            end
            s = app.ShapeDropDown.Value;
            shape1 = convertCharsToStrings(s);
            % Checking if the radius of circle is within 0.5m to 5m
            % If its outside the range throwing an out of range error.
            if shape1 == 'Circle' && (app.RadiusinmEditField.Value <0.5 || app.RadiusinmEditField.Value >5)
                app.CheckEditField_2.Value = "Input Out of Range";
                app.RadiusinmEditField.Value = 0;
                app.MajorAxisinmEditField.Value = 0;
                app.MinorAxisinmEditField.Value = 0;
                app.uEditField_2.Value = ' ';
                app.vEditField_2.Value = ' ';
                app.wEditField_2.Value = ' ';
                app.zxinMPaEditField_2.Value = ' ';
                app.zyinMPaEditField_2.Value = ' ';
                app.TorqueinNmEditField.Value = ' ';
                app.GGPaEditField_2.Value = 0;
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                delete(colorbar(app.UIAxes3,'eastoutside'));
                
            % Checking if any of the axis of ellipse is within 1m to 10m
            % If its outside the range throwing an out of range error and refreshing rest of the window.
            elseif shape1 == 'Ellipse' && (app.MajorAxisinmEditField.Value <1 ||app.MajorAxisinmEditField.Value >10 || app.MinorAxisinmEditField.Value <1 ||app.MinorAxisinmEditField.Value >10)
                app.CheckEditField_2.Value = "Input Out of Range";
                app.RadiusinmEditField.Value = 0;
                app.MajorAxisinmEditField.Value = 0;
                app.MinorAxisinmEditField.Value = 0;
                app.uEditField_2.Value = ' ';
                app.vEditField_2.Value = ' ';
                app.wEditField_2.Value = ' ';
                app.zxinMPaEditField_2.Value = ' ';
                app.zyinMPaEditField_2.Value = ' ';
                app.TorqueinNmEditField.Value = ' ';
                app.GGPaEditField_2.Value = 0;
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                delete(colorbar(app.UIAxes3,'eastoutside'));
            else
                syms x y z
                % Definining variables x,y,z
                t = app.InputEditField.Value;
                shap = app.ShapeDropDown.Value;
                shape = convertCharsToStrings(shap);
                %If the selected shape is Circle using r to form the function
                %i.e f = x^2 +y^2 -r^2
                if shape == 'Circle'
                    r = app.RadiusinmEditField.Value;
                    f = sym(x^2/(r^2) + y^2/(r^2) -1);
                    torque = floor((t*g*pi*r^4)/2);
                    %Using direct formula to calculte torque for J = (pi*r^4)/2
                    torq = torque/10^8;
                    app.TorqueinNmEditField.Value = string(torq);
                %If the selected shape is Ellipse using r to form the function
                %i.e f = x^2/a1^2 +y^2/b1^2 -1
                elseif shape == 'Ellipse'
                    a = app.MajorAxisinmEditField.Value;
                    b = app.MinorAxisinmEditField.Value;
                    a1 = a/2;% a1 is the semi-major axis
                    b1 = b/2;% b1 is the semi minor axis
                    f =sym(x^2/(a1^2) +y^2/(b1^2) -1);
                    torque = floor((t*g*pi*a1^3*b1^3)/(a1^2+b1^2));
                    %Using direct formula to calculate torque for elliptical
                    %surface.
                    torq = torque/ 10^8;
                    app.TorqueinNmEditField.Value = string(torq);
                end
                f_x = diff(f,x);
                f__x = diff(f_x,x); %double differentiating the function wrt x
                
                
                f_y = diff(f,y);
                f__y = diff(f_y,y); %double differentiating the function wrt y
                
                G = f__x + f__y;
                check1 = diff(G,x);
                check2 = diff(G,y);
                %checking if d2f/d2x + d2f/d2y is a function of x or y.
                % If g is a function of x,y then we can not compute anything
            
                if (check1 == 0 && check2 == 0)
                    B = simplify((-2*g*t)/G);%Calculating the constant B for Prandall Stress Function
                else 
                    B = 0;
                end
            
                if B ~= 0 
                    Phi = B*f;% This Phi is the prandall stress function
                    stress_zx = simplify(diff(Phi,y)); %Computing stresses
                    stress_zy = simplify(-diff(Phi,x));
                    gamma_zx = simplify(stress_zx/g);%Computing strains
                    gamma_zy = simplify(stress_zy/g);
                    %All other terms of the stress and strain will be 0.
                    
                    c = simplify(diff(gamma_zx,y) - diff(gamma_zy,x));
                    %Overall Stress as a function of x and y
                    tau = simplify((stress_zx^2 + stress_zy^2)^(1/2));
                    % Calculation of Warping Function
                    a1 = simplify(gamma_zx + t*y);
                    a1 = expand(a1);
                    a2 = simplify(gamma_zy - t*x);
                    w1 = simplify(int(a1,x));
                    w2 = simplify(diff(w1,y));
                    extra = simplify(a2-w2);
                    warping = simplify(w1 + int(extra,y));
                    % Calculation of u, v, w from the input thetha and warping
                    % function
                    u = string(simplify(-t*y*z));
                    v = string(simplify(t*x*z));
                    w = string(simplify(t*warping));
                    % Outputing the obtained values as a function of x,y,z
                    app.uEditField_2.Value = u;
                    app.vEditField_2.Value = v;
                    app.wEditField_2.Value = w;
                    zx = stress_zx/10^6;
                    zy = stress_zy/10^6;
                    app.zxinMPaEditField_2.Value = string(zx);
                    app.zyinMPaEditField_2.Value = string(zy);
                    
                    % Plotting the plots
                    
                    A = waitbar(0,'Please wait...');
                    a=0;
                    % Defining a matrix from all the points from a 10 * 10
                    % square at defininte intervals
                    [X,Y]=meshgrid(-5:.2:5);
                    W=zeros(size(X));
                    sigma=zeros(size(X));
                    
                    len=length(X)^2;
                    for i =1:length(X)
                        for j=1:length(X)
                            x = X(i,j);
                            y = Y(i,j);
                            %Calculating values of warping aat only those
                            %points that are inside the input geometry
                            if subs(f)<=0
                                W(i,j)= subs(warping);
                                
                            else
                                W(i,j)= 0;
                                
                            end
                            %Calculating the values of sigma_zx and sigma_zy
                            %are all the points to get a stress intensity plot
                            sigma_zx=subs(stress_zx);
                            sigma_zy=subs(stress_zy);
                            sigma(i,j)=sqrt(sigma_zx^2+sigma_zy^2);
                            a=a+1;
                            waitbar(a/len,A,strcat('Calculating...',{'  '},num2str(floor((a*100)/len)),' %'));
                            
                        end
                    end 
                    %Plotting 
                    S = surface(app.UIAxes2,X,Y,W);
                    view(app.UIAxes2,3)
                    
                    contourf(app.UIAxes3,X,Y,sigma);
                    %daspect(app.UIAxes4,[3 3 3]);
                    colorbar(app.UIAxes3,'eastoutside');
                    %daspect(app.UIAxes2,[3 3 3]);
                    daspect(app.UIAxes3,[3 3 3]);
                    close(A)
                    %if the input function is wrong just display the wrong
                    %function in the Check field
                else 
                    app.CheckEditField.Value = "Wrong Function";
                end
            end 
        end

        % Drop down opening function: MaterialDropDown_2
        function MaterialDropDown_2Opening(app, event)
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes);
            val1 = app.MaterialSwitch_3.Value;
            %Checking if the Material is given or G is given
            value1 = convertCharsToStrings(val1);
            %Converting input From char to strings
            if value1 == 'G'
                g = app.GGPaEditField.Value;
                g = g*1000000000;
                %Input is g in GPA. So multiplying with a factor of 10^9.
            else
                %If a particular material is chosen value is assigned as
                %per our collected Data
                val2 = app.MaterialDropDown_3.Value;
                value = convertCharsToStrings(val2);
                if value == 'Steel'
                    % G for steel is 200 GPa
                    g = 200 *1000000000;
                elseif value == 'Stainless_Steel'
                    % G for stainless steel is 193 GPa
                    g = 193 *1000000000;
                elseif value == 'Aluminium' 
                    % G for aluminium is 70.9 GPa
                    g = 70.9 *1000000000;
                elseif value =='Cast_Iron'
                    % G for cast iron is 120.5 GPa
                    g = 120.5 *1000000000;
                elseif value =='Copper'
                    % G for copper is 117 GPa
                    g = 117 *1000000000;
                elseif value == 'Yellow_Brass'
                    % G for yellow brass is 105 GPa
                    g = 105 *1000000000;
                elseif value =='Titanium'
                    % G for titanium is 114 GPa
                    g = 114 *1000000000;
                elseif value =='Concrete'
                    % G for concrete is 25 GPa
                    g = 25 *1000000000;
                elseif value=='Commercial_Bronze'
                    % G for commercial bronze is 110 GPa
                    g = 110 *1000000000';
                end
            end
            syms x y z
            % Definining variables x,y,z
            t = app.InputEditField_2.Value;
            F = app.FunctionInputEditField.Value;
            f = str2sym(F);
            
            f_x = diff(f,x);
            f__x = diff(f_x,x);%double differentiating the function wrt x
            
            
            f_y = diff(f,y);
            f__y = diff(f_y,y);%double differentiating the function wrt y

            
            G = f__x + f__y;
            check1 = diff(G,x);
            check2 = diff(G,y);
            
            if (check1 == 0 && check2 == 0)
                %Calculating the constant B for Prandall Stress Function
                B = simplify((-2*g*t)/G);
                %checking if d2f/d2x + d2f/d2y is a function of x or y.
            % If g is a function of x,y then we can not compute anything

            else 
                B = 0;
            end
            
            if B ~= 0 
                app.CheckEditField.Value = "Correct Function";
                Phi = B*f;% This Phi is the prandall stress function
                stress_zx = simplify(diff(Phi,y));%Computing stresses
                stress_zy = simplify(-diff(Phi,x));
                gamma_zx = simplify(stress_zx/g);%Computing strains
                gamma_zy = simplify(stress_zy/g);
                %All other terms of the stress and strain will be 0.
                c = simplify(diff(gamma_zx,y) - diff(gamma_zy,x));
                tau = simplify((stress_zx^2 + stress_zy^2)^(1/2));
                 %Overall Stress as a function of x and y
                 % Calculation of Warping Function
                a1 = simplify(gamma_zx + t*y);
                a1 = expand(a1);
                a2 = simplify(gamma_zy - t*x);
                w1 = simplify(int(a1,x));
                w2 = simplify(diff(w1,y));
                extra = simplify(a2-w2);
                warping = simplify(w1 + int(extra,y));
                % Calculation of u, v, w from the input thetha and warping
                % function
                u = string(simplify(-t*y*z));
                v = string(simplify(t*x*z));
                w = string(simplify(t*warping));
                % Outputing the obtained values as a function of x,y,z
                app.uEditField_3.Value = u;
                app.vEditField_3.Value = v;
                app.wEditField_3.Value = w;
                zx = stress_zx/10^6;
                zy = stress_zy/10^6;
                app.zxinMPaEditField.Value = string(zx);
                app.zyinMPaEditField.Value = string(zy);
                
                % Plotting the plots
                A = waitbar(0,'Please wait...');
                a=0;
                % Defining a matrix from all the points from a 10 * 10
                % square at defininte intervals
                [X,Y]=meshgrid(-5:.2:5);
                W=zeros(size(X));
                sigma=zeros(size(X));
                cross_sec=zeros(size(X));
                len=length(X)^2;
                for i =1:length(X)
                    for j=1:length(X)
                        x = X(i,j);
                        y = Y(i,j);
                        
                        
                        if subs(f)<=0
                            %Calculating values of warping aat only those
                        %points that are inside the input geometry
                            W(i,j)= subs(warping);
                            cross_sec(i,j)=1;
                            
                        else
                            W(i,j)= 0;
                            
                            
                        end
                         %Calculating the values of sigma_zx and sigma_zy
                        %are all the points to get a stress intensity plot
                        sigma_zx=subs(stress_zx);
                        sigma_zy=subs(stress_zy);
                        sigma(i,j)=sqrt(sigma_zx^2+sigma_zy^2);
                        
                        a=a+1;
                        waitbar(a/len,A,strcat('Calculating...',{'  '},num2str(floor((a*100)/len)),' %'));
                        
                    end
                end 
                
                S = surface(app.UIAxes,X,Y,W);
                view(app.UIAxes,3)
                surface(app.UIAxes5,X,Y,cross_sec);
                contourf(app.UIAxes4,X,Y,sigma);
                 %daspect(app.UIAxes,[3 3 3]);
                 daspect(app.UIAxes5,[3 3 3]);
                 daspect(app.UIAxes4,[3 3 3]);
                colorbar(app.UIAxes4,'eastoutside');
                close(A)
            %if the input function is wrong just display the wrong
                %function in the Check field
            else 
                % Clearing rest of the field and displaying Wrong Function
                app.CheckEditField.Value = "Wrong Function";
                app.uEditField_3.Value = ' ';
                app.vEditField_3.Value = ' ';
                app.wEditField_3.Value = ' ';
                app.zxinMPaEditField.Value = ' ';
                app.zyinMPaEditField.Value = ' ';
                app.GGPaEditField.Value = 0;
                cla(app.UIAxes5);
                cla(app.UIAxes4);
                cla(app.UIAxes);
                delete(colorbar(app.UIAxes4,'eastoutside'));
            end
            
        end

        % Menu selected function: TheoryManualMenu
        function TheoryManualMenuSelected(app, event)
            % Opening the theory manual
            open TheoryManual.html
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.uEditField_3.Value = ''; 
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            %Refreshing the screen
            app.RadiusinmEditField.Value = 0;
            app.MajorAxisinmEditField.Value = 0;
            app.MinorAxisinmEditField.Value = 0;
            app.uEditField_2.Value = ' ';
            app.vEditField_2.Value = ' ';
            app.wEditField_2.Value = ' ';
            app.zxinMPaEditField_2.Value = ' ';
            app.zyinMPaEditField_2.Value = ' ';
            app.TorqueinNmEditField.Value = ' ';
            app.GGPaEditField_2.Value = 0;
            app.CheckEditField_2.Value = ' ';
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            delete(colorbar(app.UIAxes3,'eastoutside'));
        end

        % Button pushed function: RefreshButton_4
        function RefreshButton_4Pushed(app, event)
            %Refreshing the screen
            app.FunctionInputEditField.Value = ' ';
            app.uEditField_3.Value = ' ';
            app.vEditField_3.Value = ' ';
            app.wEditField_3.Value = ' ';
            app.zxinMPaEditField.Value = ' ';
            app.zyinMPaEditField.Value = ' ';
            app.CheckEditField.Value = ' ';
            cla(app.UIAxes5);
            cla(app.UIAxes4);
            cla(app.UIAxes);
            delete(colorbar(app.UIAxes4,'eastoutside'));
        end

        % Menu selected function: FailcaseManualMenu
        function FailcaseManualMenuSelected(app, event)
            open FailCaseManual.html
        end

        % Menu selected function: AppGuideMenu
        function AppGuideMenuSelected(app, event)
            open Usermanual.html
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TortioninArbitraryShaftsUIFigure = uifigure('Visible', 'off');
            app.TortioninArbitraryShaftsUIFigure.Name = 'Tortion in Arbitrary Shafts';
            app.HelpMenu = uimenu(app.TortioninArbitraryShaftsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AppGuideMenu = uimenu(app.HelpMenu);
            app.AppGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @AppGuideMenuSelected, true);
            app.AppGuideMenu.Text = 'App Guide';
            app.TheoryManualMenu = uimenu(app.HelpMenu);
            app.TheoryManualMenu.MenuSelectedFcn = createCallbackFcn(app, @TheoryManualMenuSelected, true);
            app.TheoryManualMenu.Text = 'Theory Manual';
            app.FailcaseManualMenu = uimenu(app.HelpMenu);
            app.FailcaseManualMenu.MenuSelectedFcn = createCallbackFcn(app, @FailcaseManualMenuSelected, true);
            app.FailcaseManualMenu.Text = 'Fail case Manual';
            app.TabGroup = uitabgroup(app.TortioninArbitraryShaftsUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StandardShapesTab = uitab(app.TabGroup);
            app.StandardShapesTab.Title = 'Standard Shapes';
            app.ShapeSelectionPanel = uipanel(app.StandardShapesTab);
            app.ShapeSelectionPanel.Title = 'Shape Selection';
            app.ShapeDropDownLabel = uilabel(app.ShapeSelectionPanel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.ShapeSelectionPanel);
            app.ShapeDropDown.Items = {'Circle', 'Ellipse'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Circle';
            app.RadiusinmEditFieldLabel = uilabel(app.ShapeSelectionPanel);
            app.RadiusinmEditFieldLabel.Text = 'Radius in (m)';
            app.RadiusinmEditField = uieditfield(app.ShapeSelectionPanel, 'numeric');
            app.MajorAxisinmEditFieldLabel = uilabel(app.ShapeSelectionPanel);
            app.MajorAxisinmEditFieldLabel.Enable = 'off';
            app.MajorAxisinmEditFieldLabel.Text = 'Major Axis in (m)';
            app.MajorAxisinmEditField = uieditfield(app.ShapeSelectionPanel, 'numeric');
            app.MajorAxisinmEditField.Enable = 'off';
            app.MinorAxisinmEditFieldLabel = uilabel(app.ShapeSelectionPanel);
            app.MinorAxisinmEditFieldLabel.Enable = 'off';
            app.MinorAxisinmEditFieldLabel.Text = 'Minor Axis in (m)';
            app.MinorAxisinmEditField = uieditfield(app.ShapeSelectionPanel, 'numeric');
            app.MinorAxisinmEditField.Enable = 'off';
            app.MaterialSelectionPanel_2 = uipanel(app.StandardShapesTab);
            app.MaterialSelectionPanel_2.Title = 'Material Selection';
            app.MaterialSwitch_2 = uiswitch(app.MaterialSelectionPanel_2, 'slider');
            app.MaterialSwitch_2.Items = {'G', 'Material'};
            app.MaterialSwitch_2.ValueChangedFcn = createCallbackFcn(app, @MaterialSwitch_2ValueChanged, true);
            app.MaterialSwitch_2.Value = 'Material';
            app.MaterialDropDown_2Label = uilabel(app.MaterialSelectionPanel_2);
            app.MaterialDropDown_2Label.Text = {'Material'; ''};
            app.MaterialDropDown_2 = uidropdown(app.MaterialSelectionPanel_2);
            app.MaterialDropDown_2.Items = {'Steel', 'Stainless_Steel', 'Cast_Iron', 'Aluminium', 'Copper', 'Titanium', 'Concrete', 'Yellow_Brass', 'Commercial_Bronze'};
            app.MaterialDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @MaterialDropDown_2Opening, true);
            app.MaterialDropDown_2.Value = 'Steel';
            app.GGPaEditField_2Label = uilabel(app.MaterialSelectionPanel_2);
            app.GGPaEditField_2Label.Enable = 'off';
            app.GGPaEditField_2Label.Text = 'G (GPa)';
            app.GGPaEditField_2 = uieditfield(app.MaterialSelectionPanel_2, 'numeric');
            app.GGPaEditField_2.Enable = 'off';
            app.OutputPanel = uipanel(app.StandardShapesTab);
            app.OutputPanel.Title = 'Output';
            app.uEditField_2Label = uilabel(app.OutputPanel);
            app.uEditField_2Label.Text = {'u'; ''};
            app.uEditField_2 = uieditfield(app.OutputPanel, 'text');
            app.uEditField_2.Editable = 'off';
            app.vEditField_2Label = uilabel(app.OutputPanel);
            app.vEditField_2Label.Text = 'v';
            app.vEditField_2 = uieditfield(app.OutputPanel, 'text');
            app.vEditField_2.Editable = 'off';
            app.wEditField_2Label = uilabel(app.OutputPanel);
            app.wEditField_2Label.Text = 'w';
            app.wEditField_2 = uieditfield(app.OutputPanel, 'text');
            app.wEditField_2.Editable = 'off';
            app.zxinMPaEditField_2Label = uilabel(app.OutputPanel);
            app.zxinMPaEditField_2Label.Text = 'σ_zx in (MPa)';
            app.zxinMPaEditField_2 = uieditfield(app.OutputPanel, 'text');
            app.zxinMPaEditField_2.Editable = 'off';
            app.zyinMPaEditField_2Label = uilabel(app.OutputPanel);
            app.zyinMPaEditField_2Label.Text = 'σ_zy in (MPa)';
            app.zyinMPaEditField_2 = uieditfield(app.OutputPanel, 'text');
            app.zyinMPaEditField_2.Editable = 'off';
            app.TorqueinNmEditFieldLabel = uilabel(app.StandardShapesTab);
            app.TorqueinNmEditFieldLabel.Text = 'Torque in (N.m)';
            app.TorqueinNmEditField = uieditfield(app.StandardShapesTab, 'text');
            app.TorqueinNmEditField.Editable = 'off';
            app.InputEditFieldLabel = uilabel(app.StandardShapesTab);
            app.InputEditFieldLabel.Text = 'θ(Input)';
            app.InputEditField = uieditfield(app.StandardShapesTab, 'numeric');
            app.InputEditField.Value = 0.01;
            app.CalculateButton_2 = uibutton(app.StandardShapesTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.x108Label = uilabel(app.StandardShapesTab);
            app.x108Label.Text = 'x 10^8';
            app.RefreshButton_3 = uibutton(app.StandardShapesTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.CheckEditField_2Label = uilabel(app.StandardShapesTab);
            app.CheckEditField_2Label.Text = 'Check';
            app.CheckEditField_2 = uieditfield(app.StandardShapesTab, 'text');
            app.UIAxes2 = uiaxes(app.StandardShapesTab);
            title(app.UIAxes2, 'Warping Function')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.StandardShapesTab);
            title(app.UIAxes3, 'Stress Distribution')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ArbitraryShapesTab = uitab(app.TabGroup);
            app.ArbitraryShapesTab.Title = 'Arbitrary Shapes';
            app.FunctionInputEditFieldLabel = uilabel(app.ArbitraryShapesTab);
            app.FunctionInputEditFieldLabel.Text = {'Function(Input)'; ''};
            app.FunctionInputEditField = uieditfield(app.ArbitraryShapesTab, 'text');
            app.OutputPanel_2 = uipanel(app.ArbitraryShapesTab);
            app.OutputPanel_2.Title = 'Output';
            app.uEditField_3Label = uilabel(app.OutputPanel_2);
            app.uEditField_3Label.Text = {'u'; ''};
            app.uEditField_3 = uieditfield(app.OutputPanel_2, 'text');
            app.uEditField_3.Editable = 'off';
            app.vEditField_3Label = uilabel(app.OutputPanel_2);
            app.vEditField_3Label.Text = 'v';
            app.vEditField_3 = uieditfield(app.OutputPanel_2, 'text');
            app.vEditField_3.Editable = 'off';
            app.wEditField_3Label = uilabel(app.OutputPanel_2);
            app.wEditField_3Label.Text = 'w';
            app.wEditField_3 = uieditfield(app.OutputPanel_2, 'text');
            app.wEditField_3.Editable = 'off';
            app.zxinMPaLabel = uilabel(app.OutputPanel_2);
            app.zxinMPaLabel.Text = 'σ_zx in (MPa)';
            app.zxinMPaEditField = uieditfield(app.OutputPanel_2, 'text');
            app.zxinMPaEditField.Editable = 'off';
            app.zyinMPaEditFieldLabel = uilabel(app.OutputPanel_2);
            app.zyinMPaEditFieldLabel.Text = 'σ_zy in (MPa)';
            app.zyinMPaEditField = uieditfield(app.OutputPanel_2, 'text');
            app.zyinMPaEditField.Editable = 'off';
            app.CalculateButton = uibutton(app.ArbitraryShapesTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialSelectionPanel_3 = uipanel(app.ArbitraryShapesTab);
            app.MaterialSelectionPanel_3.Title = 'Material Selection';
            app.MaterialSwitch_3 = uiswitch(app.MaterialSelectionPanel_3, 'slider');
            app.MaterialSwitch_3.Items = {'G', 'Material'};
            app.MaterialSwitch_3.ValueChangedFcn = createCallbackFcn(app, @MaterialSwitch_3ValueChanged, true);
            app.MaterialSwitch_3.Value = 'Material';
            app.GGPaLabel = uilabel(app.MaterialSelectionPanel_3);
            app.GGPaLabel.Enable = 'off';
            app.GGPaLabel.Text = 'G (GPa)';
            app.GGPaEditField = uieditfield(app.MaterialSelectionPanel_3, 'numeric');
            app.GGPaEditField.Enable = 'off';
            app.MaterialDropDown_2Label_2 = uilabel(app.MaterialSelectionPanel_3);
            app.MaterialDropDown_2Label_2.Text = {'Material'; ''};
            app.MaterialDropDown_3 = uidropdown(app.MaterialSelectionPanel_3);
            app.MaterialDropDown_3.Items = {'Steel', 'Stainless_Steel', 'Cast_Iron', 'Aluminium', 'Copper', 'Titanium', 'Concrete', 'Yellow_Brass', 'Commercial_Bronze'};
            app.MaterialDropDown_3.Value = 'Stainless_Steel';
            app.CheckEditFieldLabel = uilabel(app.ArbitraryShapesTab);
            app.CheckEditFieldLabel.Text = 'Check';
            app.CheckEditField = uieditfield(app.ArbitraryShapesTab, 'text');
            app.InputEditField_2Label = uilabel(app.ArbitraryShapesTab);
            app.InputEditField_2Label.Text = 'θ(Input)';
            app.InputEditField_2 = uieditfield(app.ArbitraryShapesTab, 'numeric');
            app.InputEditField_2.Value = 0.01;
            app.RefreshButton_4 = uibutton(app.ArbitraryShapesTab, 'push');
            app.RefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_4Pushed, true);
            app.RefreshButton_4.Text = 'Refresh';
            app.UIAxes = uiaxes(app.ArbitraryShapesTab);
            title(app.UIAxes, 'Warping Function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes4 = uiaxes(app.ArbitraryShapesTab);
            title(app.UIAxes4, 'Stress Distribution')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.ArbitraryShapesTab);
            title(app.UIAxes5, 'Cross Section')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            % Show the figure after all components are created
            app.TortioninArbitraryShaftsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TortioninArbitraryShaftsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TortioninArbitraryShaftsUIFigure)
        end
    end
end